classdef App_THPPT_CuoiKi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        GiiThiuNhmTab                 matlab.ui.container.Tab
        NhimvtmhiuthuttonvcchgiithchLabel_2  matlab.ui.control.Label
        Lp21DTV_CLC3Label_3           matlab.ui.control.Label
        MSSV21207177Label             matlab.ui.control.Label
        HvTnNguynNgcBoLmLabel         matlab.ui.control.Label
        Image2_2                      matlab.ui.control.Image
        NhimvNhmtrngvtnghpcodecaccthnhvinLabel  matlab.ui.control.Label
        NhimvtmhiuthuttonvcchgiithchLabel  matlab.ui.control.Label
        Lp21DTV_CLC3Label_2           matlab.ui.control.Label
        Lp21DTV_CLC3Label             matlab.ui.control.Label
        nCuiKMnThcHnhPhngPhpTnhLabel  matlab.ui.control.Label
        MSSV21207178Label             matlab.ui.control.Label
        HvTnNguynTnLcLabel            matlab.ui.control.Label
        MSSV21207205Label             matlab.ui.control.Label
        HvTnNguynNgcMinhQunLabel      matlab.ui.control.Label
        Image3                        matlab.ui.control.Image
        Image2                        matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        NghimTab                      matlab.ui.container.Tab
        fx_label_nghiem               matlab.ui.control.Label
        fx_nghiem1                    matlab.ui.control.EditField
        saiso_nghiem                  matlab.ui.control.NumericEditField
        NhpkhongsaisEditFieldLabel    matlab.ui.control.Label
        b_nghiem                      matlab.ui.control.NumericEditField
        NhpkhongphnlybEditFieldLabel  matlab.ui.control.Label
        a_nghiem                      matlab.ui.control.NumericEditField
        NhpkhongphnlyaEditFieldLabel  matlab.ui.control.Label
        solanlap_nghiem               matlab.ui.control.EditField
        SoLanLapEditFieldLabel        matlab.ui.control.Label
        ketqua_nghiem                 matlab.ui.control.EditField
        KetquaNghiemEditFieldLabel    matlab.ui.control.Label
        timnghiem_nghiem              matlab.ui.control.Button
        select_phuongphap_nghiem      matlab.ui.container.ButtonGroup
        newton_nghiem                 matlab.ui.control.ToggleButton
        lap_nghiem                    matlab.ui.control.ToggleButton
        chiadoi_nghiem                matlab.ui.control.ToggleButton
        phuongtrinh_nghiem            matlab.ui.control.EditField
        NhpphngtrnhFpLabel_2          matlab.ui.control.Label
        bieudo_nghiem                 matlab.ui.control.UIAxes
        NiSuyTab                      matlab.ui.container.Tab
        bac_noisuy                    matlab.ui.control.EditField
        ylathcbcEditFieldLabel        matlab.ui.control.Label
        giatricannoisuy_noisuy        matlab.ui.control.NumericEditField
        TnhktqunisuyButton            matlab.ui.control.Button
        ketquanoisuy_noisuy           matlab.ui.control.EditField
        KtQuNiSuyLabel                matlab.ui.control.Label
        GitrcnnisuyLabel              matlab.ui.control.Label
        x_noisuy                      matlab.ui.control.EditField
        ketqua_noisuy                 matlab.ui.control.EditField
        KtQuaThcNiSuyLabel            matlab.ui.control.Label
        NhapDuLieuXEditFieldLabel     matlab.ui.control.Label
        y_noisuy                      matlab.ui.control.EditField
        NhapDuLieuYEditFieldLabel     matlab.ui.control.Label
        TmathcnisuyButton             matlab.ui.control.Button
        ChonPhuongPhapNoiSuyButtonGroup  matlab.ui.container.ButtonGroup
        select_langrange_noisuy       matlab.ui.control.ToggleButton
        select_newton_noisuy          matlab.ui.control.ToggleButton
        bieudo_noisuy                 matlab.ui.control.UIAxes
        HiQuyTab                      matlab.ui.container.Tab
        giatridudoan_hoiquy           matlab.ui.control.NumericEditField
        NhpgitrcndonEditFieldLabel    matlab.ui.control.Label
        ThchinktquButton              matlab.ui.control.Button
        KtqudonEditField              matlab.ui.control.EditField
        KtqudonEditFieldLabel         matlab.ui.control.Label
        x_hoiquy                      matlab.ui.control.EditField
        NhapDuLieuXEditField_2Label   matlab.ui.control.Label
        ketqua_pt_hoiquy              matlab.ui.control.EditField
        KtQuphngtrnhhiquyEditFieldLabel  matlab.ui.control.Label
        y_hoiquy                      matlab.ui.control.EditField
        NhapDuLieuYEditField_2Label   matlab.ui.control.Label
        ThchintmphngtrnhButton        matlab.ui.control.Button
        ChnphngphphiquyButtonGroup    matlab.ui.container.ButtonGroup
        select_lg_hoiquy              matlab.ui.control.ToggleButton
        select_hm_hoiquy              matlab.ui.control.ToggleButton
        select_tt_hoiquy              matlab.ui.control.ToggleButton
        bieudo_hoiquy                 matlab.ui.control.UIAxes
        oHmTab                        matlab.ui.container.Tab
        SaisButtonGroup               matlab.ui.container.ButtonGroup
        h2Button                      matlab.ui.control.ToggleButton
        OhButton                      matlab.ui.control.ToggleButton
        h_daoham                      matlab.ui.control.NumericEditField
        BcHLabel                      matlab.ui.control.Label
        giatricandaoham_daoham        matlab.ui.control.NumericEditField
        GitrcnohmEditFieldLabel       matlab.ui.control.Label
        ketqua_daoham                 matlab.ui.control.EditField
        KtQuEditFieldLabel            matlab.ui.control.Label
        oHmButton                     matlab.ui.control.Button
        ChnphngphpohmButtonGroup      matlab.ui.container.ButtonGroup
        xapxilui_daoham               matlab.ui.control.ToggleButton
        xapxitrungtam_daoham          matlab.ui.control.ToggleButton
        xapxitien_daoham              matlab.ui.control.ToggleButton
        HocLabel                      matlab.ui.control.Label
        fx_daoham                     matlab.ui.control.EditField
        NhphmsEditFieldLabel          matlab.ui.control.Label
        y_daoham                      matlab.ui.control.EditField
        NhpdliuYEditFieldLabel        matlab.ui.control.Label
        x_daoham                      matlab.ui.control.EditField
        NhpdliuXEditFieldLabel        matlab.ui.control.Label
        TchPhnTab                     matlab.ui.container.Tab
        saiso_tichphan                matlab.ui.control.EditField
        SaisEditFieldLabel            matlab.ui.control.Label
        n_tichphan                    matlab.ui.control.NumericEditField
        NhpNEditFieldLabel            matlab.ui.control.Label
        canduoitichphan               matlab.ui.control.NumericEditField
        NhpcntrnLabel                 matlab.ui.control.Label
        cantren_tichphan              matlab.ui.control.NumericEditField
        NhpcndiLabel                  matlab.ui.control.Label
        ketqua_tichphan               matlab.ui.control.EditField
        KtQuEditField_2Label          matlab.ui.control.Label
        fx_tichphan                   matlab.ui.control.EditField
        NhphmsEditField_2Label        matlab.ui.control.Label
        oHmButton_2                   matlab.ui.control.Button
        ChnphngphpTchPhnButtonGroup   matlab.ui.container.ButtonGroup
        simpson38_tp                  matlab.ui.control.ToggleButton
        simpson13_tp                  matlab.ui.control.ToggleButton
        select_hinhthang_tp           matlab.ui.control.ToggleButton
    end

    
    methods (Access = private)    


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: timnghiem_nghiem
        function timnghiem_nghiemButtonPushed(app, event)
            n = 0;
            syms x;
            w = app.a_nghiem.Value:app.b_nghiem.Value;
            fx = str2func(['@(x)',app.phuongtrinh_nghiem.Value]);
            fx1 = str2func(['@(x)',app.fx_nghiem1.Value]);
            if(app.chiadoi_nghiem.Value == 1)
                chiadoi(app,fx,n,w);
            elseif(app.lap_nghiem.Value == 1)
                lap(app,fx,n,fx1);
            elseif(app.newton_nghiem.Value == 1)
                tieptuyen(app,fx);
            end
        end

        % Button pushed function: TmathcnisuyButton
        function TmathcnisuyButtonPushed(app, event)
                x = str2num(app.x_noisuy.Value);
                y = str2num(app.y_noisuy.Value);
            if(app.select_langrange_noisuy.Value == 1)
                [res,bac] = langrange(x,y);
            else
                [res,bac] = newton(x,y);
            end
                app.bac_noisuy.Value = num2str(bac);
                app.ketqua_noisuy.Value = string(res);
            for i = 1:length(x)
                z = subs(res,x);
            end
            plot(app.bieudo_noisuy,x,y,"*",x,z,'red');
        end

        % Button pushed function: TnhktqunisuyButton
        function TnhktqunisuyButtonPushed(app, event)
              x = str2num(app.x_noisuy.Value);
               y = str2num(app.y_noisuy.Value);
            if(app.select_langrange_noisuy.Value == 1)
                [res,bac] = langrange(x,y);
                 app.ketquanoisuy_noisuy.Value = string(subs(res,app.giatricannoisuy_noisuy.Value));
            else    
                [res,bac] = newton(x,y);
                 app.ketquanoisuy_noisuy.Value = string(subs(res,app.giatricannoisuy_noisuy.Value));
            end
        end

        % Button pushed function: ThchintmphngtrnhButton
        function ThchintmphngtrnhButtonPushed(app, event)
            a = str2num(app.x_hoiquy.Value);
            b = str2num(app.y_hoiquy.Value);
            syms x;
            if(app.select_tt_hoiquy.Value == 1)
                [a1,a0,r2] = Regress(a,b);
                yx = a0 + a1*x;
                y2 = a0 + a1.*a;
                yx = expand(yx);
                app.ketqua_pt_hoiquy.Value = string(yx);
            elseif (app.select_hm_hoiquy.Value == 1)
                [a1,a0,r2] = Regress(log10(a),log10(b));
                p = 10.^a0;
                l = a1;
                y2 = p * a.^l;
                yx = p*x.^l;
                yx = expand(yx);
                app.ketqua_pt_hoiquy.Value = string(yx);
            else
                [a1,a0,r2] = Regress(a,log(b));
                p = exp(a0);
                l = a1;
                y2 = p*exp(l*a);
                yx = p*exp(a1.*x);
                yx = string(yx);
                disp(yx);
                app.ketqua_pt_hoiquy.Value = yx;
            end
            plot(app.bieudo_hoiquy,a,b,'o',a,y2);
        end

        % Button pushed function: oHmButton
        function oHmButtonPushed(app, event)
            if(app.x_daoham.Value ~= "" && app.y_daoham.Value ~="")
               x = str2num(app.x_daoham.Value);
               y = str2num(app.y_daoham.Value);
               if(app.OhButton.Value == 1)
                   dx = daoham_xy_oh(app,x,y,app.giatricandaoham_daoham.Value);
               else
                   dx = daohamxy_oh2(app,x,y,app.giatricandaoham_daoham.Value);
               end
               app.ketqua_daoham.Value = num2str(dx);
            elseif (app.fx_daoham.Value ~= "" && app.h_daoham.Value ~= 0)
                fx = str2func(['@(x)',app.fx_daoham.Value]);
                h = app.h_daoham.Value;
                if(app.h2Button.Value == 1)
                    dx = daoham_fx_oh2(fx,app.giatricandaoham_daoham.Value,h,app);
                else
                    dx = daoham_fx_oh(app,fx,h,app.giatricandaoham_daoham.Value);
                end
                app.ketqua_daoham.Value = num2str(dx);
            end
        end

        % Button pushed function: oHmButton_2
        function oHmButton_2Pushed(app, event)
             fx = str2func(['@(x)',app.fx_tichphan.Value]);
             if (app.select_hinhthang_tp.Value == 1)
                [dx,saiso] = tichphanhinhthang(fx,app.cantren_tichphan.Value,app.canduoitichphan.Value,app.n_tichphan.Value); 
             elseif(app.simpson13_tp.Value == 1)
                [dx,saiso] =  tichphanhinhthangsimpson1_3(fx,app.cantren_tichphan.Value,app.canduoitichphan.Value,app.n_tichphan.Value);
             else
                 [dx,saiso] = tichphanhinhthang_38(fx,app.cantren_tichphan.Value,app.canduoitichphan.Value,app.n_tichphan.Value);
             end
                app.ketqua_tichphan.Value = num2str(dx);
                app.saiso_tichphan.Value = string(saiso); 
        end

        % Button pushed function: ThchinktquButton
        function ThchinktquButtonPushed(app, event)
            x0 = app.giatridudoan_hoiquy.Value;
            z1 = str2func(['@(x)',app.ketqua_pt_hoiquy.Value]);
            app.KtqudonEditField.Value = num2str(z1(x0));
        end

        % Selection changed function: select_phuongphap_nghiem
        function select_phuongphap_nghiemSelectionChanged(app, event)
            selectedButton = app.select_phuongphap_nghiem.SelectedObject;
            if(app.lap_nghiem.Value == 1)
                app.fx_nghiem1.Visible = 1;
                app.fx_label_nghiem.Visible = 1;
            else   
                app.fx_label_nghiem.Visible = 0;
                app.fx_nghiem1.Visible = 0;
            end
        end

        % Selection changed function: ChnphngphpohmButtonGroup
        function ChnphngphpohmButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChnphngphpohmButtonGroup.SelectedObject;
            if(app.xapxitrungtam_daoham.Value == 1)
                app.OhButton.Visible = 0;
            else
                app.OhButton.Visible = 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GiiThiuNhmTab = uitab(app.TabGroup);
            app.GiiThiuNhmTab.Title = 'Giới Thiệu Nhóm';
            app.Image = uiimage(app.GiiThiuNhmTab);
            app.Image.ImageSource = 'logo_preview_rev_1.png';
            app.Image2 = uiimage(app.GiiThiuNhmTab);
            app.Image2.ImageSource = 'TanLoc_21207178.jpg';
            app.Image3 = uiimage(app.GiiThiuNhmTab);
            app.Image3.ImageSource = 'MinhQuan_21207205.jpg';
            app.HvTnNguynNgcMinhQunLabel = uilabel(app.GiiThiuNhmTab);
            app.HvTnNguynNgcMinhQunLabel.Text = 'Họ và Tên: Nguyễn Ngọc Minh Quân';
            app.MSSV21207205Label = uilabel(app.GiiThiuNhmTab);
            app.MSSV21207205Label.Text = 'MSSV: 21207205';
            app.HvTnNguynTnLcLabel = uilabel(app.GiiThiuNhmTab);
            app.HvTnNguynTnLcLabel.Text = 'Họ và Tên: Nguyễn Tấn Lộc';
            app.MSSV21207178Label = uilabel(app.GiiThiuNhmTab);
            app.MSSV21207178Label.Text = 'MSSV: 21207178';
            app.nCuiKMnThcHnhPhngPhpTnhLabel = uilabel(app.GiiThiuNhmTab);
            app.nCuiKMnThcHnhPhngPhpTnhLabel.Text = 'Đồ Án Cuối Kì Môn Thực Hành Phương Pháp Tính';
            app.Lp21DTV_CLC3Label = uilabel(app.GiiThiuNhmTab);
            app.Lp21DTV_CLC3Label.Text = 'Lớp: 21DTV_CLC3';
            app.Lp21DTV_CLC3Label_2 = uilabel(app.GiiThiuNhmTab);
            app.Lp21DTV_CLC3Label_2.Text = 'Lớp: 21DTV_CLC3';
            app.NhimvtmhiuthuttonvcchgiithchLabel = uilabel(app.GiiThiuNhmTab);
            app.NhimvtmhiuthuttonvcchgiithchLabel.Text = 'Nhiệm vụ: tìm hiểu thuật toán và cách giải thích';
            app.NhimvNhmtrngvtnghpcodecaccthnhvinLabel = uilabel(app.GiiThiuNhmTab);
            app.NhimvNhmtrngvtnghpcodecaccthnhvinLabel.Text = 'Nhiệm vụ: Nhóm trưởng và tổng hợp code của các thành viên';
            app.Image2_2 = uiimage(app.GiiThiuNhmTab);
            app.Image2_2.ImageSource = 'BaoLam_21207177.jpg';
            app.HvTnNguynNgcBoLmLabel = uilabel(app.GiiThiuNhmTab);
            app.HvTnNguynNgcBoLmLabel.Text = 'Họ và Tên: Nguyễn Ngọc Bảo Lâm';
            app.MSSV21207177Label = uilabel(app.GiiThiuNhmTab);
            app.MSSV21207177Label.Text = 'MSSV: 21207177';
            app.Lp21DTV_CLC3Label_3 = uilabel(app.GiiThiuNhmTab);
            app.Lp21DTV_CLC3Label_3.Text = 'Lớp: 21DTV_CLC3';
            app.NhimvtmhiuthuttonvcchgiithchLabel_2 = uilabel(app.GiiThiuNhmTab);
            app.NhimvtmhiuthuttonvcchgiithchLabel_2.Text = 'Nhiệm vụ: tìm hiểu thuật toán và cách giải thích';
            app.NghimTab = uitab(app.TabGroup);
            app.NghimTab.Title = 'Nghiệm';
            app.bieudo_nghiem = uiaxes(app.NghimTab);
            title(app.bieudo_nghiem, 'Title')
            xlabel(app.bieudo_nghiem, 'X')
            ylabel(app.bieudo_nghiem, 'Y')
            zlabel(app.bieudo_nghiem, 'Z')
            app.NhpphngtrnhFpLabel_2 = uilabel(app.NghimTab);
            app.NhpphngtrnhFpLabel_2.Text = 'Nhập phương trình(Fp)';
            app.phuongtrinh_nghiem = uieditfield(app.NghimTab, 'text');
            app.phuongtrinh_nghiem.Value = '2 - 0.13*log(x)';
            app.select_phuongphap_nghiem = uibuttongroup(app.NghimTab);
            app.select_phuongphap_nghiem.SelectionChangedFcn = createCallbackFcn(app, @select_phuongphap_nghiemSelectionChanged, true);
            app.select_phuongphap_nghiem.Title = 'Chọn phương pháp tìm';
            app.chiadoi_nghiem = uitogglebutton(app.select_phuongphap_nghiem);
            app.chiadoi_nghiem.Text = 'Chia đôi';
            app.chiadoi_nghiem.Value = true;
            app.lap_nghiem = uitogglebutton(app.select_phuongphap_nghiem);
            app.lap_nghiem.Text = 'Lặp';
            app.newton_nghiem = uitogglebutton(app.select_phuongphap_nghiem);
            app.newton_nghiem.Text = 'Newton (tiếp tuyến)';
            app.timnghiem_nghiem = uibutton(app.NghimTab, 'push');
            app.timnghiem_nghiem.ButtonPushedFcn = createCallbackFcn(app, @timnghiem_nghiemButtonPushed, true);
            app.timnghiem_nghiem.Text = 'Tìm Nghiệm';
            app.KetquaNghiemEditFieldLabel = uilabel(app.NghimTab);
            app.KetquaNghiemEditFieldLabel.Text = 'Ket qua Nghiem';
            app.ketqua_nghiem = uieditfield(app.NghimTab, 'text');
            app.ketqua_nghiem.Editable = 'off';
            app.SoLanLapEditFieldLabel = uilabel(app.NghimTab);
            app.SoLanLapEditFieldLabel.Text = 'So Lan Lap';
            app.solanlap_nghiem = uieditfield(app.NghimTab, 'text');
            app.solanlap_nghiem.Editable = 'off';
            app.NhpkhongphnlyaEditFieldLabel = uilabel(app.NghimTab);
            app.NhpkhongphnlyaEditFieldLabel.Text = 'Nhập khoảng phân ly a';
            app.a_nghiem = uieditfield(app.NghimTab, 'numeric');
            app.a_nghiem.Value = 1;
            app.NhpkhongphnlybEditFieldLabel = uilabel(app.NghimTab);
            app.NhpkhongphnlybEditFieldLabel.Text = 'Nhập khoảng phân ly b';
            app.b_nghiem = uieditfield(app.NghimTab, 'numeric');
            app.b_nghiem.Value = 2;
            app.NhpkhongsaisEditFieldLabel = uilabel(app.NghimTab);
            app.NhpkhongsaisEditFieldLabel.Text = 'Nhập khoảng sai số';
            app.saiso_nghiem = uieditfield(app.NghimTab, 'numeric');
            app.saiso_nghiem.Value = 0.005;
            app.fx_nghiem1 = uieditfield(app.NghimTab, 'text');
            app.fx_nghiem1.Visible = 'off';
            app.fx_nghiem1.Value = '2 - 0.13*log(x)';
            app.fx_label_nghiem = uilabel(app.NghimTab);
            app.fx_label_nghiem.Visible = 'off';
            app.fx_label_nghiem.Text = 'Nhập phương trình (Fx)';
            app.NiSuyTab = uitab(app.TabGroup);
            app.NiSuyTab.Title = 'Nội Suy';
            app.bieudo_noisuy = uiaxes(app.NiSuyTab);
            title(app.bieudo_noisuy, 'Title')
            xlabel(app.bieudo_noisuy, 'X')
            ylabel(app.bieudo_noisuy, 'Y')
            zlabel(app.bieudo_noisuy, 'Z')
            app.ChonPhuongPhapNoiSuyButtonGroup = uibuttongroup(app.NiSuyTab);
            app.ChonPhuongPhapNoiSuyButtonGroup.Title = 'Chon Phuong Phap Noi Suy';
            app.select_newton_noisuy = uitogglebutton(app.ChonPhuongPhapNoiSuyButtonGroup);
            app.select_newton_noisuy.Text = 'Nội Suy Newton';
            app.select_newton_noisuy.Value = true;
            app.select_langrange_noisuy = uitogglebutton(app.ChonPhuongPhapNoiSuyButtonGroup);
            app.select_langrange_noisuy.Text = 'Nội Suy Lagrange';
            app.TmathcnisuyButton = uibutton(app.NiSuyTab, 'push');
            app.TmathcnisuyButton.ButtonPushedFcn = createCallbackFcn(app, @TmathcnisuyButtonPushed, true);
            app.TmathcnisuyButton.Text = 'Tìm đa thức nội suy';
            app.NhapDuLieuYEditFieldLabel = uilabel(app.NiSuyTab);
            app.NhapDuLieuYEditFieldLabel.Text = 'Nhap Du Lieu Y';
            app.y_noisuy = uieditfield(app.NiSuyTab, 'text');
            app.NhapDuLieuXEditFieldLabel = uilabel(app.NiSuyTab);
            app.NhapDuLieuXEditFieldLabel.Text = 'Nhap Du Lieu X';
            app.KtQuaThcNiSuyLabel = uilabel(app.NiSuyTab);
            app.KtQuaThcNiSuyLabel.Text = 'Kết Quả Đa Thức Nội Suy';
            app.ketqua_noisuy = uieditfield(app.NiSuyTab, 'text');
            app.ketqua_noisuy.Editable = 'off';
            app.x_noisuy = uieditfield(app.NiSuyTab, 'text');
            app.GitrcnnisuyLabel = uilabel(app.NiSuyTab);
            app.GitrcnnisuyLabel.Text = 'Giá trị cần nội suy';
            app.KtQuNiSuyLabel = uilabel(app.NiSuyTab);
            app.KtQuNiSuyLabel.Text = 'Kết Quả Nội Suy';
            app.ketquanoisuy_noisuy = uieditfield(app.NiSuyTab, 'text');
            app.ketquanoisuy_noisuy.Editable = 'off';
            app.TnhktqunisuyButton = uibutton(app.NiSuyTab, 'push');
            app.TnhktqunisuyButton.ButtonPushedFcn = createCallbackFcn(app, @TnhktqunisuyButtonPushed, true);
            app.TnhktqunisuyButton.Text = 'Tính kết quả nội suy';
            app.giatricannoisuy_noisuy = uieditfield(app.NiSuyTab, 'numeric');
            app.ylathcbcEditFieldLabel = uilabel(app.NiSuyTab);
            app.ylathcbcEditFieldLabel.Text = 'Đây là đa thức bậc ';
            app.bac_noisuy = uieditfield(app.NiSuyTab, 'text');
            app.HiQuyTab = uitab(app.TabGroup);
            app.HiQuyTab.Title = 'Hồi Quy';
            app.bieudo_hoiquy = uiaxes(app.HiQuyTab);
            title(app.bieudo_hoiquy, 'Title')
            xlabel(app.bieudo_hoiquy, 'X')
            ylabel(app.bieudo_hoiquy, 'Y')
            zlabel(app.bieudo_hoiquy, 'Z')
            app.ChnphngphphiquyButtonGroup = uibuttongroup(app.HiQuyTab);
            app.ChnphngphphiquyButtonGroup.Title = 'Chọn phương pháp hồi quy';
            app.select_tt_hoiquy = uitogglebutton(app.ChnphngphphiquyButtonGroup);
            app.select_tt_hoiquy.Text = 'Tuyến Tính';
            app.select_tt_hoiquy.Value = true;
            app.select_hm_hoiquy = uitogglebutton(app.ChnphngphphiquyButtonGroup);
            app.select_hm_hoiquy.Text = 'Hàm Mũ';
            app.select_lg_hoiquy = uitogglebutton(app.ChnphngphphiquyButtonGroup);
            app.select_lg_hoiquy.Text = 'Logarit';
            app.ThchintmphngtrnhButton = uibutton(app.HiQuyTab, 'push');
            app.ThchintmphngtrnhButton.ButtonPushedFcn = createCallbackFcn(app, @ThchintmphngtrnhButtonPushed, true);
            app.ThchintmphngtrnhButton.Text = 'Thực hiện tìm phương trình';
            app.NhapDuLieuYEditField_2Label = uilabel(app.HiQuyTab);
            app.NhapDuLieuYEditField_2Label.Text = 'Nhap Du Lieu Y';
            app.y_hoiquy = uieditfield(app.HiQuyTab, 'text');
            app.KtQuphngtrnhhiquyEditFieldLabel = uilabel(app.HiQuyTab);
            app.KtQuphngtrnhhiquyEditFieldLabel.Text = 'Kết Quả phương trình hồi quy';
            app.ketqua_pt_hoiquy = uieditfield(app.HiQuyTab, 'text');
            app.NhapDuLieuXEditField_2Label = uilabel(app.HiQuyTab);
            app.NhapDuLieuXEditField_2Label.Text = 'Nhap Du Lieu X';
            app.x_hoiquy = uieditfield(app.HiQuyTab, 'text');
            app.KtqudonEditFieldLabel = uilabel(app.HiQuyTab);
            app.KtqudonEditFieldLabel.Text = 'Kết quả dự đoán';
            app.KtqudonEditField = uieditfield(app.HiQuyTab, 'text');
            app.KtqudonEditField.Editable = 'off';
            app.ThchinktquButton = uibutton(app.HiQuyTab, 'push');
            app.ThchinktquButton.ButtonPushedFcn = createCallbackFcn(app, @ThchinktquButtonPushed, true);
            app.ThchinktquButton.Text = 'Thực hiện kết quả ';
            app.NhpgitrcndonEditFieldLabel = uilabel(app.HiQuyTab);
            app.NhpgitrcndonEditFieldLabel.Text = 'Nhập giá trị cần dự đoán';
            app.giatridudoan_hoiquy = uieditfield(app.HiQuyTab, 'numeric');
            app.oHmTab = uitab(app.TabGroup);
            app.oHmTab.Title = 'Đạo Hàm';
            app.NhpdliuXEditFieldLabel = uilabel(app.oHmTab);
            app.NhpdliuXEditFieldLabel.Text = 'Nhập dữ liệu X';
            app.x_daoham = uieditfield(app.oHmTab, 'text');
            app.NhpdliuYEditFieldLabel = uilabel(app.oHmTab);
            app.NhpdliuYEditFieldLabel.Text = 'Nhập dữ liệu Y';
            app.y_daoham = uieditfield(app.oHmTab, 'text');
            app.NhphmsEditFieldLabel = uilabel(app.oHmTab);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số';
            app.fx_daoham = uieditfield(app.oHmTab, 'text');
            app.HocLabel = uilabel(app.oHmTab);
            app.HocLabel.Text = 'Hoặc';
            app.ChnphngphpohmButtonGroup = uibuttongroup(app.oHmTab);
            app.ChnphngphpohmButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChnphngphpohmButtonGroupSelectionChanged, true);
            app.ChnphngphpohmButtonGroup.Title = 'Chọn phương pháp đạo hàm';
            app.xapxitien_daoham = uitogglebutton(app.ChnphngphpohmButtonGroup);
            app.xapxitien_daoham.Text = 'Xấp xỉ tiến';
            app.xapxitien_daoham.Value = true;
            app.xapxitrungtam_daoham = uitogglebutton(app.ChnphngphpohmButtonGroup);
            app.xapxitrungtam_daoham.Text = 'Trung Tâm';
            app.xapxilui_daoham = uitogglebutton(app.ChnphngphpohmButtonGroup);
            app.xapxilui_daoham.Text = 'Xấp xỉ lùi';
            app.oHmButton = uibutton(app.oHmTab, 'push');
            app.oHmButton.ButtonPushedFcn = createCallbackFcn(app, @oHmButtonPushed, true);
            app.oHmButton.Text = 'Đạo Hàm';
            app.KtQuEditFieldLabel = uilabel(app.oHmTab);
            app.KtQuEditFieldLabel.Text = 'Kết Quả';
            app.ketqua_daoham = uieditfield(app.oHmTab, 'text');
            app.GitrcnohmEditFieldLabel = uilabel(app.oHmTab);
            app.GitrcnohmEditFieldLabel.Text = 'Giá trị cần đạo hàm';
            app.giatricandaoham_daoham = uieditfield(app.oHmTab, 'numeric');
            app.BcHLabel = uilabel(app.oHmTab);
            app.BcHLabel.Text = 'Bước H';
            app.h_daoham = uieditfield(app.oHmTab, 'numeric');
            app.SaisButtonGroup = uibuttongroup(app.oHmTab);
            app.SaisButtonGroup.Title = 'Sai số';
            app.OhButton = uitogglebutton(app.SaisButtonGroup);
            app.OhButton.Text = 'O(h)';
            app.OhButton.Value = true;
            app.h2Button = uitogglebutton(app.SaisButtonGroup);
            app.h2Button.Text = '0(h^2)';
            app.TchPhnTab = uitab(app.TabGroup);
            app.TchPhnTab.Title = 'Tích Phân';
            app.ChnphngphpTchPhnButtonGroup = uibuttongroup(app.TchPhnTab);
            app.ChnphngphpTchPhnButtonGroup.Title = 'Chọn phương pháp Tích Phân';
            app.select_hinhthang_tp = uitogglebutton(app.ChnphngphpTchPhnButtonGroup);
            app.select_hinhthang_tp.Text = 'Hình Thang';
            app.select_hinhthang_tp.Value = true;
            app.simpson13_tp = uitogglebutton(app.ChnphngphpTchPhnButtonGroup);
            app.simpson13_tp.Text = 'Simpson 1/3';
            app.simpson38_tp = uitogglebutton(app.ChnphngphpTchPhnButtonGroup);
            app.simpson38_tp.Text = 'Simpson 3/8';
            app.oHmButton_2 = uibutton(app.TchPhnTab, 'push');
            app.oHmButton_2.ButtonPushedFcn = createCallbackFcn(app, @oHmButton_2Pushed, true);
            app.oHmButton_2.Text = 'Đạo Hàm';
            app.NhphmsEditField_2Label = uilabel(app.TchPhnTab);
            app.NhphmsEditField_2Label.Text = 'Nhập hàm số';
            app.fx_tichphan = uieditfield(app.TchPhnTab, 'text');
            app.fx_tichphan.Value = 'sin(x)*x.^3';
            app.KtQuEditField_2Label = uilabel(app.TchPhnTab);
            app.KtQuEditField_2Label.Text = 'Kết Quả';
            app.ketqua_tichphan = uieditfield(app.TchPhnTab, 'text');
            app.NhpcndiLabel = uilabel(app.TchPhnTab);
            app.NhpcndiLabel.Text = 'Nhập cận dưới';
            app.cantren_tichphan = uieditfield(app.TchPhnTab, 'numeric');
            app.NhpcntrnLabel = uilabel(app.TchPhnTab);
            app.NhpcntrnLabel.Text = 'Nhập cận trên';
            app.canduoitichphan = uieditfield(app.TchPhnTab, 'numeric');
            app.canduoitichphan.Value = 1;
            app.NhpNEditFieldLabel = uilabel(app.TchPhnTab);
            app.NhpNEditFieldLabel.Text = 'Nhập N';
            app.n_tichphan = uieditfield(app.TchPhnTab, 'numeric');
            app.n_tichphan.Value = 10;
            app.SaisEditFieldLabel = uilabel(app.TchPhnTab);
            app.SaisEditFieldLabel.Text = 'Sai số';
            app.saiso_tichphan = uieditfield(app.TchPhnTab, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_THPPT_CuoiKi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end